classdef modulation_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FrequencySlider_2       matlab.ui.control.Slider
        FrequencySlider_2Label  matlab.ui.control.Label
        FrequencySlider         matlab.ui.control.Slider
        FrequencySliderLabel    matlab.ui.control.Label
        AmplitudeSlider_2       matlab.ui.control.Slider
        AmplitudeSlider_2Label  matlab.ui.control.Label
        AmplitudeSlider         matlab.ui.control.Slider
        AmplitudeSliderLabel    matlab.ui.control.Label
        CarrierSignalLabel      matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        MODULATIONLabel         matlab.ui.control.Label
        MessageSignalLabel      matlab.ui.control.Label
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = plotting(app, am, ac, fm, fc)
            t = 0:0.001:1;

            mt = am*cos(2*pi*fm*t);
            plot(app.UIAxes, t, mt);

            ct = ac*cos(2*pi*fc*10*t);
            plot(app.UIAxes_2, t, ct);

            st = (ac+mt).*cos(2*pi*fc*10*t);
            plot(app.UIAxes_3, t, st);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: AmplitudeSlider
        function AmplitudeSliderValueChanging(app, event)
            changingValue = event.Value;
            amplitude = changingValue;
            app.plotting(amplitude, app.AmplitudeSlider_2.Value, app.FrequencySlider.Value, app.FrequencySlider_2.Value);
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            changingValue = event.Value;
            frequency = changingValue;
            app.plotting(app.AmplitudeSlider.Value, app.AmplitudeSlider_2.Value, frequency, app.FrequencySlider_2.Value);
        end

        % Value changing function: AmplitudeSlider_2
        function AmplitudeSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            amplitude = changingValue;
            app.plotting(app.AmplitudeSlider.Value, amplitude, app.FrequencySlider.Value, app.FrequencySlider_2.Value);
        end

        % Value changing function: FrequencySlider_2
        function FrequencySlider_2ValueChanging(app, event)
            changingValue = event.Value;
            frequency = changingValue;
            app.plotting(app.AmplitudeSlider.Value, app.AmplitudeSlider_2.Value, app.FrequencySlider.Value, frequency);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Message Signal')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Carrier Signal')
            xlabel(app.UIAxes_2, 'time')
            ylabel(app.UIAxes_2, 'amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Modulated Signal')
            xlabel(app.UIAxes_3, 'time')
            ylabel(app.UIAxes_3, 'amplitude')
            zlabel(app.UIAxes_3, 'Z')
            app.MessageSignalLabel = uilabel(app.UIFigure);
            app.MessageSignalLabel.Text = 'Message Signal';
            app.Panel = uipanel(app.UIFigure);
            app.MODULATIONLabel = uilabel(app.Panel);
            app.MODULATIONLabel.Text = 'MODULATION';
            app.CarrierSignalLabel = uilabel(app.UIFigure);
            app.CarrierSignalLabel.Text = 'Carrier Signal';
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.Limits = [0 10];
            app.AmplitudeSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSliderValueChanging, true);
            app.AmplitudeSlider_2Label = uilabel(app.UIFigure);
            app.AmplitudeSlider_2Label.Text = 'Amplitude';
            app.AmplitudeSlider_2 = uislider(app.UIFigure);
            app.AmplitudeSlider_2.Limits = [0 10];
            app.AmplitudeSlider_2.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSlider_2ValueChanging, true);
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [0 10];
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.FrequencySlider_2Label = uilabel(app.UIFigure);
            app.FrequencySlider_2Label.Text = 'Frequency';
            app.FrequencySlider_2 = uislider(app.UIFigure);
            app.FrequencySlider_2.Limits = [0 10];
            app.FrequencySlider_2.ValueChangingFcn = createCallbackFcn(app, @FrequencySlider_2ValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modulation_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end